classdef Game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        HelpButton   matlab.ui.control.Button
        Label        matlab.ui.control.Label
        ScoreLabel   matlab.ui.control.Label
        TanksButton  matlab.ui.control.Button
        SnakeButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %%% Tanks %%%
        Property
        xdim = 100;
        ydim = 100;
        
        turn = "right" % The direction right--left--up--down
        bullet = "hold" % bullet = "hold" means there is no bullet in the game at the moment.
        coor = [0 50 3 3]; % [x y Width Height]
        coor_barrel = [0 50 2 2];
        
        % Bullet direction check %
        bullet_right = "False"
        bullet_left = "False"
        bullet_up = "False"
        bullet_down = "False"
        
        % Bullet location lists %
        bullet_right_list
        bullet_left_list
        bullet_up_list
        bullet_down_list

        % Lists concerning the enemies %
        enemy_list_1
        enemy_list_2
        enemy_list_3
        enemy_list_4

        counter_clock = 0 
        enemy_hit_counter = 0 
        game_type 
        level = 1 
        remcheck = 5 % Remainder check effects the enemy spawn rate
        
        %%% Snake Game %%%
        tail_coor 
        snake_length = 4 % Snake starting length
        dot_list
    end
    
    methods (Access = public)
        

        function results = dot_spawn(app)
%               DOT_SPAWN(APP) Spawns a random dot in the map
%           This function is called after every successful consumption done
%           by the snake
            dot_choose_x = randi([10 app.xdim-10],1); % Chooses a random x location in the map.
            dot_choose_y = randi([10 app.ydim-10],1); % Chooses a random y location in the map.
            app.dot_list = [dot_choose_x dot_choose_y 1 1]; % Adds the dot to the list.
            
        end
        
        function results = move_snake(app)
%               MOVE_SNAKE Moves the snake in the figure.
%           This function is called once.
            try
            app.ScoreLabel.Text = " ";
            hold(app.UIAxes, "on")
            xlim(app.UIAxes,[0, app.xdim])
            ylim(app.UIAxes,[0, app.ydim])
            
            % When right arrow pressed %
            while strcmp(app.turn,"right")
                
                if size(app.tail_coor,1) < app.snake_length
                    app.tail_coor = [app.tail_coor;app.coor];
                elseif size(app.tail_coor,1) == app.snake_length
                   app.tail_coor = [app.tail_coor;app.coor];
                   app.tail_coor(1,:) = [];
                end
                
                body_speed = 2;
                cla(app.UIAxes)
                app.coor(1) = app.coor(1)+body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0 0.4470 0.7410], "LineWidth", 1)
                for i=1:size(app.tail_coor)
                    rectangle(app.UIAxes,"Position",app.tail_coor(i,:), "FaceColor", [0 0 0], "LineWidth", 1, "EdgeColor","k" )
                end
                if size(app.dot_list, 1) > 0
                    rectangle(app.UIAxes,"Position",app.dot_list(1,:), "FaceColor", [1 0 0], "LineWidth", 1)
                else
                    dot_spawn(app)
                end
                if norm([app.coor(1,1)+1.5 app.coor(1,2)+1.5]-app.dot_list(1,1:2))<2.5
                    app.snake_length = app.snake_length + 2;
                    app.dot_list = [];
                end
                for i=1:size(app.tail_coor)
                    if app.coor(1,1)+1.5 > app.xdim+2
                        app.coor(1) = 0;
                    elseif norm([app.coor(1,1)+1.5 app.coor(1,2)+1.5]-[app.tail_coor(i,1)+1.5 app.tail_coor(i,2)+1.5])<1
                        app.Label.Text = "Game Over";
                        pause(0.05)
                        clear all
                        close all force
                    end
                end
                pause(0.05)
            end
            
            % When down arrow pressed %
            while strcmp(app.turn,"down")
                if size(app.tail_coor,1) < app.snake_length
                    app.tail_coor = [app.tail_coor;app.coor];
                elseif size(app.tail_coor,1) == app.snake_length
                   app.tail_coor = [app.tail_coor;app.coor];
                   app.tail_coor(1,:) = [];
                end
                body_speed = 2;
                cla(app.UIAxes)
                app.coor(2) = app.coor(2)-body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0 0.4470 0.7410], "LineWidth", 1)
                for i=1:size(app.tail_coor)
                    rectangle(app.UIAxes,"Position",app.tail_coor(i,:), "FaceColor", [0 0 0], "LineWidth", 1, "EdgeColor","k" )
                end
                if size(app.dot_list, 1) > 0
                    rectangle(app.UIAxes,"Position",app.dot_list(1,:), "FaceColor", [1 0 0], "LineWidth", 1)
                else
                    dot_spawn(app)
                end
                if norm(app.coor(1,1:2)-app.dot_list(1,1:2))<2.5
                    app.snake_length = app.snake_length + 2;
                    app.dot_list = [];
                end
                for i=1:size(app.tail_coor)
                    if  app.coor(1,2)+1.5 < -2
                        app.coor(2) = app.ydim;
                    elseif norm([app.coor(1,1)+1.5 app.coor(1,2)+1.5]-[app.tail_coor(i,1)+1.5 app.tail_coor(i,2)+1.5])<1
                        app.Label.Text = "Game Over";
                        pause(0.05)
                        clear all
                        close all force
                    end
                end
                pause(0.05)
            end
            
            % When up arrow pressed %
            while strcmp(app.turn,"up")
                if size(app.tail_coor,1) < app.snake_length
                    app.tail_coor = [app.tail_coor;app.coor];
                elseif size(app.tail_coor,1) == app.snake_length
                   app.tail_coor = [app.tail_coor;app.coor];
                   app.tail_coor(1,:) = [];
                end
                body_speed = 2;
                cla(app.UIAxes)
                app.coor(2) = app.coor(2)+body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0 0.4470 0.7410], "LineWidth", 1)
                for i=1:size(app.tail_coor)
                    rectangle(app.UIAxes,"Position",app.tail_coor(i,:), "FaceColor", [0 0 0], "LineWidth", 1, "EdgeColor","k" )
                end
                if size(app.dot_list, 1) > 0
                    rectangle(app.UIAxes,"Position",app.dot_list(1,:), "FaceColor", [1 0 0], "LineWidth", 1)
                else
                    dot_spawn(app)
                end
                if norm(app.coor(1,1:2)-app.dot_list(1,1:2))<2.5
                    app.snake_length = app.snake_length + 2;
                    app.dot_list = [];
                end
                for i=1:size(app.tail_coor)
                    if  app.coor(1,2)+1.5 > app.ydim+2
                        app.coor(2) = 0;
                    elseif norm([app.coor(1,1)+1.5 app.coor(1,2)+1.5]-[app.tail_coor(i,1)+1.5 app.tail_coor(i,2)+1.5])<1
                        app.Label.Text = "Game Over";
                        pause(0.05)
                        clear all
                        close all force
                    end
                end
                pause(0.05)
            end
            
            % When left arrow pressed %
            while strcmp(app.turn,"left")
                if size(app.tail_coor,1) < app.snake_length
                    app.tail_coor = [app.tail_coor;app.coor];
                elseif size(app.tail_coor,1) == app.snake_length
                   app.tail_coor = [app.tail_coor;app.coor];
                   app.tail_coor(1,:) = [];
                end
                body_speed = 2;
                cla(app.UIAxes)
                app.coor(1) = app.coor(1)-body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0 0.4470 0.7410], "LineWidth", 1)
                for i=1:size(app.tail_coor)
                    rectangle(app.UIAxes,"Position",app.tail_coor(i,:), "FaceColor", [0 0 0], "LineWidth", 1, "EdgeColor","k" )
                end
                if size(app.dot_list, 1) > 0
                    rectangle(app.UIAxes,"Position",app.dot_list(1,:), "FaceColor", [1 0 0], "LineWidth", 1)
                else
                    dot_spawn(app)
                end
                if norm(app.coor(1,1:2)-app.dot_list(1,1:2))<2.5
                    app.snake_length = app.snake_length + 2;
                    app.dot_list = [];
                    
                end
                for i=1:size(app.tail_coor)
                    if  app.coor(1,1)+1.5 < -2
                        app.coor(1) = app.xdim;
                    elseif norm([app.coor(1,1)+1.5 app.coor(1,2)+1.5]-[app.tail_coor(i,1)+1.5 app.tail_coor(i,2)+1.5])<1
                        app.Label.Text = "Game Over";
                        pause(0.05)
                        clear all
                        close all force
                    end
                end
                pause(0.05)
            end
            
            
            catch
            end
        end
            
        
        function results = enemyspawn(app)
%               ENEMYSPAWN Spawns a random enemy coming from a random direction.
%           Called occasionally.

            enemy_freq = 50-app.level*3;
            app.counter_clock = app.counter_clock + 1;
            body_speed = 1/2;
            if rem(app.counter_clock,enemy_freq) == 0
                enemy_choose = randi([1 4],1);
                
                % left enemy spawn %
                if enemy_choose == 1
                    y = randi([1 app.ydim],1);
                    el = [0 y 3 3];
                    app.enemy_list_1 = [app.enemy_list_1; el];
                    
                % right enemy spawn %
                elseif enemy_choose == 2
                    y = randi([1 app.ydim],1);
                    el = [app.xdim y 3 3];
                    app.enemy_list_2 = [app.enemy_list_2; el];
                    
                % down enemy spawn %
                elseif enemy_choose == 3
                    x = randi([1 app.xdim],1);
                    el = [x 0 3 3];
                    app.enemy_list_3 = [app.enemy_list_3; el];
                    
                % up enemy spawn %
                elseif enemy_choose == 4
                    x = randi([1 app.xdim],1);
                    el = [x app.ydim 3 3];
                    app.enemy_list_4 = [app.enemy_list_4; el];
                end
            end
                % left enemy draw %
                if size(app.enemy_list_1,1)>0
                    for i=1:size(app.enemy_list_1(:,1))
                        app.enemy_list_1(i,1) = app.enemy_list_1(i,1)+body_speed;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.enemy_list_1(i,:), "FaceColor", [1 0 0], "LineWidth", 1)
                        if app.enemy_list_1(i,1) > 102
                            app.enemy_list_1(i,:) = [];
                        end
                        
                    end
                end
                
                % right enemy draw %
                if size(app.enemy_list_2,1)>0
                    for i=1:size(app.enemy_list_2,1)
                        app.enemy_list_2(i,1) = app.enemy_list_2(i,1)-body_speed;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.enemy_list_2(i,:), "FaceColor", [1 0 0], "LineWidth", 1)
                        if app.enemy_list_2(i,1) < -2
                            app.enemy_list_2(i,:) = [];
                        end
                    end
                end
                
                % down enemy draw %
                if size(app.enemy_list_3,1)>0
                    for i=1:size(app.enemy_list_3,1)
                        app.enemy_list_3(i,2) = app.enemy_list_3(i,2)+body_speed;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.enemy_list_3(i,:), "FaceColor", [1 0 0], "LineWidth", 1)
                        if app.enemy_list_3(i,2) > 102
                            app.enemy_list_3(i,:) = [];
                        end
                    end
                end
                
                % up enemy draw %
                if size(app.enemy_list_4,1)>0
                    for i=1:size(app.enemy_list_4,1)
                        app.enemy_list_4(i,2) = app.enemy_list_4(i,2)-body_speed;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.enemy_list_4(i,:), "FaceColor", [1 0 0], "LineWidth", 1)
                        if app.enemy_list_4(i,2) < -2
                            app.enemy_list_4(i,:) = [];
                        end
                    end
                end  
        end    
        function results = move(app)
%               MOVE(APP) Moves the tank in the map.
%           Also includes the bullets' location and the score system
%           Called occasionally.

            try
            hold(app.UIAxes, "on")
            xlim(app.UIAxes,[0, app.xdim])
            ylim(app.UIAxes,[0, app.ydim])
            
            % When right arrow pressed %
            while strcmp(app.turn,"right")
                if strcmp(app.bullet,"fire")
                    bl = [app.coor(1)+2,app.coor(2)+1,app.coor(3)/4,app.coor(4)/4];
                    app.bullet_right_list = [app.bullet_right_list; bl];
                    app.bullet_right = "True";
                    app.bullet = "hold";
                end
                body_speed = 1/2;
                cla(app.UIAxes)
                enemyspawn(app)
                app.coor(1) = app.coor(1)+body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                app.coor_barrel = app.coor;
                app.coor_barrel(1) = app.coor_barrel(1) + 5/2;
                app.coor_barrel(2) = app.coor_barrel(2) + 1;
                app.coor_barrel(3) = app.coor_barrel(3)-1;
                app.coor_barrel(4) = app.coor_barrel(4) - 2;
                rectangle(app.UIAxes,"Position",app.coor_barrel, "FaceColor", [0.5 0.5 0.5], "LineWidth", 0.1)
                for j=1:size(app.enemy_list_1,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_1(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_2,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_2(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_3,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_3(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_4,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_4(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                
                % Right bullet trail %
                if size(app.bullet_right_list,1)>0
                    if app.bullet_right_list(1,1) > app.xdim
                        app.bullet_right_list(1,:) = [];
                    end
                else
                    app.bullet_right = "False";
                end


                if strcmp(app.bullet_right,"True")
                    for i=1:size(app.bullet_right_list,1)
                        app.bullet_right_list(i,1) = app.bullet_right_list(i,1)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_right_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        
                        % Right enemy hit %
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                
                % Left bullet trail %
                if size(app.bullet_left_list,1)>0
                    if app.bullet_left_list(1,1) < 0
                        app.bullet_left_list(1,:) = [];
                    end
                else
                    app.bullet_left = "False";
                end
                if strcmp(app.bullet_left,"True")
                    for i=1:size(app.bullet_left_list(:,1))
                        app.bullet_left_list(i,1) = app.bullet_left_list(i,1)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_left_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                
                % Up bullet trail %
                if size(app.bullet_up_list,1)>0
                    if app.bullet_up_list(1,2) > app.ydim
                        app.bullet_up_list(1,:) = [];
                    end
                else
                    app.bullet_up = "False";
                end
                if strcmp(app.bullet_up,"True")
                    for i=1:size(app.bullet_up_list(:,1))
                        app.bullet_up_list(i,2) = app.bullet_up_list(i,2)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_up_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                % Down bullet trail %
                if size(app.bullet_down_list,1)>0
                    if app.bullet_down_list(1,2) < 0
                        app.bullet_down_list(1,:) = [];
                    end
                else
                    app.bullet_down = "False";
                end
                if strcmp(app.bullet_down,"True")
                    for i=1:size(app.bullet_down_list(:,1))
                        app.bullet_down_list(i,2) = app.bullet_down_list(i,2)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_down_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                pause(0.05)
                
            end
            % When down arrow pressed %
            while strcmp(app.turn,"down")
                
                if strcmp(app.bullet,"fire")
                    bl = [app.coor(1)+1,app.coor(2)-2,app.coor(3)/4,app.coor(4)/4];
                    app.bullet_down_list = [app.bullet_down_list; bl];
                    app.bullet_down = "True";
                    app.bullet = "hold";
                end
                body_speed = 1/2;
                cla(app.UIAxes)
                enemyspawn(app)
                app.coor(2) = app.coor(2)-body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                app.coor_barrel = app.coor;
                app.coor_barrel(1) = app.coor_barrel(1) + 1;
                app.coor_barrel(2) = app.coor_barrel(2) - 5/2;
                app.coor_barrel(3) = app.coor_barrel(3) - 2;
                app.coor_barrel(4) = app.coor_barrel(4) - 1;
                rectangle(app.UIAxes,"Position",app.coor_barrel, "FaceColor", [0.5 0.5 0.5], "LineWidth", 0.1)
                for j=1:size(app.enemy_list_1,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_1(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_2,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_2(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_3,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_3(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_4,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_4(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
               %%%---RIGHT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_right_list,1)>0
                    if app.bullet_right_list(1,1) > app.xdim
                        app.bullet_right_list(1,:) = [];
                    end
                else
                    app.bullet_right = "False";
                end
                if strcmp(app.bullet_right,"True")
                    for i=1:size(app.bullet_right_list,1)
                        app.bullet_right_list(i,1) = app.bullet_right_list(i,1)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_right_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        %%%---RİGHT ENEMY HİT---%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---LEFT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_left_list,1)>0
                    if app.bullet_left_list(1,1) < 0
                        app.bullet_left_list(1,:) = [];
                    end
                else
                    app.bullet_left = "False";
                end
                if strcmp(app.bullet_left,"True")
                    for i=1:size(app.bullet_left_list(:,1))
                        app.bullet_left_list(i,1) = app.bullet_left_list(i,1)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_left_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---UP BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_up_list,1)>0
                    if app.bullet_up_list(1,2) > app.ydim
                        app.bullet_up_list(1,:) = [];
                    end
                else
                    app.bullet_up = "False";
                end
                if strcmp(app.bullet_up,"True")
                    for i=1:size(app.bullet_up_list(:,1))
                        app.bullet_up_list(i,2) = app.bullet_up_list(i,2)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_up_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---DOWN BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_down_list,1)>0
                    if app.bullet_down_list(1,2) < 0
                        app.bullet_down_list(1,:) = [];
                    end
                else
                    app.bullet_down = "False";
                end
                if strcmp(app.bullet_down,"True")
                    for i=1:size(app.bullet_down_list(:,1))
                        app.bullet_down_list(i,2) = app.bullet_down_list(i,2)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_down_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                pause(0.05)
            end

            % When up arrow pressed %
            while strcmp(app.turn,"up")
                

                if strcmp(app.bullet,"fire")
                    bl = [app.coor(1)+1,app.coor(2)+2,app.coor(3)/4,app.coor(4)/4];
                    app.bullet_up_list = [app.bullet_up_list; bl];
                    app.bullet_up = "True";
                    app.bullet = "hold";
                end
                body_speed = 1/2;
                cla(app.UIAxes)
                enemyspawn(app)
                app.coor(2) = app.coor(2)+body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                app.coor_barrel = app.coor;
                app.coor_barrel(1) = app.coor_barrel(1) + 1;
                app.coor_barrel(2) = app.coor_barrel(2) + 5/2;
                app.coor_barrel(3) = app.coor_barrel(3) - 2;
                app.coor_barrel(4) = app.coor_barrel(4) - 1;
                rectangle(app.UIAxes,"Position",app.coor_barrel, "FaceColor", [0.5 0.5 0.5], "LineWidth", 0.1)
                for j=1:size(app.enemy_list_1,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_1(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_2,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_2(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_3,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_3(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_4,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_4(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                %%%---RIGHT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_right_list,1)>0
                    if app.bullet_right_list(1,1) > app.xdim
                        app.bullet_right_list(1,:) = [];
                    end
                else
                    app.bullet_right = "False";
                end
                if strcmp(app.bullet_right,"True")
                    for i=1:size(app.bullet_right_list,1)
                        app.bullet_right_list(i,1) = app.bullet_right_list(i,1)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_right_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        %%%---RİGHT ENEMY HİT---%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---LEFT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_left_list,1)>0
                    if app.bullet_left_list(1,1) < 0
                        app.bullet_left_list(1,:) = [];
                    end
                else
                    app.bullet_left = "False";
                end
                if strcmp(app.bullet_left,"True")
                    for i=1:size(app.bullet_left_list(:,1))
                        app.bullet_left_list(i,1) = app.bullet_left_list(i,1)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_left_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---UP BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_up_list,1)>0
                    if app.bullet_up_list(1,2) > app.ydim
                        app.bullet_up_list(1,:) = [];
                    end
                else
                    app.bullet_up = "False";
                end
                if strcmp(app.bullet_up,"True")
                    for i=1:size(app.bullet_up_list(:,1))
                        app.bullet_up_list(i,2) = app.bullet_up_list(i,2)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_up_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---DOWN BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_down_list,1)>0
                    if app.bullet_down_list(1,2) < 0
                        app.bullet_down_list(1,:) = [];
                    end
                else
                    app.bullet_down = "False";
                end
                if strcmp(app.bullet_down,"True")
                    for i=1:size(app.bullet_down_list(:,1))
                        app.bullet_down_list(i,2) = app.bullet_down_list(i,2)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_down_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                pause(0.05)
            end

            % When left arrow pressed %
            while strcmp(app.turn,"left")
                
                if strcmp(app.bullet,"fire")
                    bl = [app.coor(1)-2,app.coor(2)+1,app.coor(3)/4,app.coor(4)/4];
                    app.bullet_left_list = [app.bullet_left_list; bl];
                    app.bullet_left = "True";
                    app.bullet = "hold";
                end
                body_speed = 1/2;
                cla(app.UIAxes)
                enemyspawn(app)
                app.coor(1) = app.coor(1)-body_speed;%x coordinates of vertices
                rectangle(app.UIAxes,"Position",app.coor, "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                app.coor_barrel = app.coor;
                app.coor_barrel(1) = app.coor_barrel(1) - 5/2;
                app.coor_barrel(2) = app.coor_barrel(2) + 1;
                app.coor_barrel(3) = app.coor_barrel(3)-1;
                app.coor_barrel(4) = app.coor_barrel(4) - 2;
                rectangle(app.UIAxes,"Position",app.coor_barrel, "FaceColor", [0.5 0.5 0.5], "LineWidth", 0.1)
                for j=1:size(app.enemy_list_1,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_1(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_2,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_2(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_3,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_3(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                for j=1:size(app.enemy_list_4,1)
                    if norm(app.coor(1,1:2)-app.enemy_list_4(j,1:2))<2
                       app.Label.Text = "Game Over";
                       pause(0.05)
                       clear all
                       close all force 
                    end
                end
                %%%---RIGHT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_right_list,1)>0
                    if app.bullet_right_list(1,1) > app.xdim
                        app.bullet_right_list(1,:) = [];
                    end
                else
                    app.bullet_right = "False";
                end
                if strcmp(app.bullet_right,"True")
                    for i=1:size(app.bullet_right_list,1)
                        app.bullet_right_list(i,1) = app.bullet_right_list(i,1)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_right_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        %%%---RİGHT ENEMY HİT---%%%
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_right_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---LEFT BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_left_list,1)>0
                    if app.bullet_left_list(1,1) < 0
                        app.bullet_left_list(1,:) = [];
                    end
                else
                    app.bullet_left = "False";
                end
                if strcmp(app.bullet_left,"True")
                    for i=1:size(app.bullet_left_list(:,1))
                        app.bullet_left_list(i,1) = app.bullet_left_list(i,1)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_left_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_left_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---UP BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_up_list,1)>0
                    if app.bullet_up_list(1,2) > app.ydim
                        app.bullet_up_list(1,:) = [];
                    end
                else
                    app.bullet_up = "False";
                end
                if strcmp(app.bullet_up,"True")
                    for i=1:size(app.bullet_up_list(:,1))
                        app.bullet_up_list(i,2) = app.bullet_up_list(i,2)+body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_up_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                        if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_up_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                %%%---DOWN BULLET TRAIL---%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if size(app.bullet_down_list,1)>0
                    if app.bullet_down_list(1,2) < 0
                        app.bullet_down_list(1,:) = [];
                    end
                else
                    app.bullet_down = "False";
                end
                if strcmp(app.bullet_down,"True")
                    for i=1:size(app.bullet_down_list(:,1))
                        app.bullet_down_list(i,2) = app.bullet_down_list(i,2)-body_speed*4;%x coordinates of vertices
                        rectangle(app.UIAxes,"Position",app.bullet_down_list(i,:), "FaceColor", [0.5 0.5 0.5], "LineWidth", 3)
                    if size(app.enemy_list_1,1)>0
                            remove_list_1 = [];
                            for j=1:size(app.enemy_list_1,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_1(j,1:2))<2
                                    remove_list_1 = [remove_list_1; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_1,1)
                                app.enemy_list_1(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_2,1)>0
                            remove_list_2 = [];
                            for j=1:size(app.enemy_list_2,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_2(j,1:2))<2
                                    remove_list_2 = [remove_list_2; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_2,1)
                                app.enemy_list_2(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_3,1)>0
                            remove_list_3 = [];
                            for j=1:size(app.enemy_list_3,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_3(j,1:2))<2
                                    remove_list_3 = [remove_list_3; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_3,1)
                                app.enemy_list_3(k,:) = [];
                            end
                        end
                        if size(app.enemy_list_4,1)>0
                            remove_list_4 = [];
                            for j=1:size(app.enemy_list_4,1)
                                if norm(app.bullet_down_list(i,1:2)-app.enemy_list_4(j,1:2))<2
                                    remove_list_4 = [remove_list_4; j];
                                    app.enemy_hit_counter = app.enemy_hit_counter + 1;
                                    text = "Score: " + num2str(app.enemy_hit_counter);
                                    app.ScoreLabel.Text = text;
                                    if rem(app.enemy_hit_counter,app.remcheck) == 0
                                        app.level = app.level + 1;
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                        app.Label.Text = "Level " + num2str(app.level);
                                        pause(0.5)
                                        app.Label.Text = " ";
                                    end
                                end
                            end
                            for k=1:size(remove_list_4,1)
                                app.enemy_list_4(k,:) = [];
                            end
                        end
                    end  
                end
                pause(0.05)
            end
            catch
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % Keyboard Input %
            key = event.Key;
            switch key
                case 'rightarrow'
                    app.turn = "right";
                case 'leftarrow'
                    app.turn = "left";
                case 'uparrow'
                    app.turn = "up";
                case 'downarrow'
                    app.turn = "down";
                case 'space'
                    app.bullet = "fire";        
            end
                if strcmp(app.game_type,"tanks")
                    move(app)
                elseif strcmp(app.game_type,"snake")
                    move_snake(app) 
                end
        end

        % Callback function
        function QuitButtonPushed(app, event)
            clear all
            close all force;
        end

        % Button pushed function: TanksButton
        function TanksButtonPushed(app, event)
            app.game_type = "tanks";
            move(app)
        end

        % Button pushed function: SnakeButton
        function SnakeButtonPushed(app, event)
            app.game_type = "snake";
            move_snake(app) 
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            text = "Arrow keys to move"+newline+"Spacebar to shoot";
            uialert(app.UIFigure,text,"Instructions");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SnakeButton = uibutton(app.UIFigure, 'push');
            app.SnakeButton.ButtonPushedFcn = createCallbackFcn(app, @SnakeButtonPushed, true);
            app.SnakeButton.Text = 'Snake';
            app.TanksButton = uibutton(app.UIFigure, 'push');
            app.TanksButton.ButtonPushedFcn = createCallbackFcn(app, @TanksButtonPushed, true);
            app.TanksButton.Text = 'Tanks';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end